classdef BMI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        SignaloptionsandartefactsPanel  matlab.ui.container.Panel
        HzLabel                         matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        DurationEditFieldLabel          matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        SNREditFieldLabel               matlab.ui.control.Label
        SNREditField                    matlab.ui.control.NumericEditField
        EyeblinkCheckBox                matlab.ui.control.CheckBox
        EMGeffectsCheckBox              matlab.ui.control.CheckBox
        EOGeffectsCheckBox              matlab.ui.control.CheckBox
        FsEditFieldLabel                matlab.ui.control.Label
        FsEditField                     matlab.ui.control.NumericEditField
        MovementStartingTimeLabel       matlab.ui.control.Label
        MovementStartingTimeEditField   matlab.ui.control.NumericEditField
        GeneratedataButton              matlab.ui.control.Button
        PowerlinesCheckBox              matlab.ui.control.CheckBox
        MovementDropDownLabel           matlab.ui.control.Label
        MovementDropDown                matlab.ui.control.DropDown
        FilePanel                       matlab.ui.container.Panel
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        LoaddataButton                  matlab.ui.control.Button
        PreprocessingTab                matlab.ui.container.Tab
        SpatialFilteringPanel           matlab.ui.container.Panel
        ApplyspatialfilteringButton     matlab.ui.control.Button
        FilteringMethodDropDownLabel    matlab.ui.control.Label
        FilteringMethodDropDown         matlab.ui.control.DropDown
        TemporalFilteringPanel          matlab.ui.container.Panel
        BandPassFilterParametersPanel   matlab.ui.container.Panel
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        OrderEditFieldLabel             matlab.ui.control.Label
        OrderEditField                  matlab.ui.control.NumericEditField
        WindowTypeLabel                 matlab.ui.control.Label
        WindowTypeDropDown              matlab.ui.control.DropDown
        NotchFilterPanel                matlab.ui.container.Panel
        FreqEditFieldLabel              matlab.ui.control.Label
        FreqEditField                   matlab.ui.control.NumericEditField
        HzLabel_6                       matlab.ui.control.Label
        ApplytemporalfilteringButton    matlab.ui.control.Button
        FiltersPanel                    matlab.ui.container.Panel
        NotchFilterCheckBox             matlab.ui.control.CheckBox
        BPFilterCheckBox                matlab.ui.control.CheckBox
        FrequencybandsButtonGroup       matlab.ui.container.ButtonGroup
        ClassicalfrequencybandsButton   matlab.ui.control.RadioButton
        PersonnalisedfrequencybandsButton  matlab.ui.control.RadioButton
        ClassicalfrequencybandsPanel    matlab.ui.container.Panel
        muRythmCheckBox                 matlab.ui.control.CheckBox
        deltaRythmCheckBox              matlab.ui.control.CheckBox
        thetaRythmCheckBox              matlab.ui.control.CheckBox
        alphaRythmCheckBox              matlab.ui.control.CheckBox
        betaRythmCheckBox               matlab.ui.control.CheckBox
        PersonnalisedfrequencybandsPanel  matlab.ui.container.Panel
        NbofbandsEditFieldLabel         matlab.ui.control.Label
        NbofbandsEditField              matlab.ui.control.NumericEditField
        LFreqEditField_2Label           matlab.ui.control.Label
        LFreqEditField_perso            matlab.ui.control.NumericEditField
        HFreqEditField_2Label           matlab.ui.control.Label
        HFreqEditField_perso            matlab.ui.control.NumericEditField
        ArtifactRejectionPanel          matlab.ui.container.Panel
        ApplyrejectionButton            matlab.ui.control.Button
        ExtractionTab                   matlab.ui.container.Tab
        ExtractionMethodButtonGroup     matlab.ui.container.ButtonGroup
        PowerbandsButton                matlab.ui.control.RadioButton
        WaveletsButton                  matlab.ui.control.RadioButton
        ARButton                        matlab.ui.control.RadioButton
        PeakDetectionButton             matlab.ui.control.RadioButton
        VarianceButton                  matlab.ui.control.RadioButton
        TemplateMatchingButton          matlab.ui.control.RadioButton
        TimeIntegrationButton           matlab.ui.control.RadioButton
        BuildFeatureVectorButton        matlab.ui.control.Button
        WindowingandframingPanel        matlab.ui.container.Panel
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        WindowLengthEditField           matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        WindowOverlappingEditField_2Label  matlab.ui.control.Label
        WindowOverlappingEditField      matlab.ui.control.NumericEditField
        ARPanel                         matlab.ui.container.Panel
        OrderEditField_2Label           matlab.ui.control.Label
        AROrder                         matlab.ui.control.NumericEditField
        WaveletsPanel                   matlab.ui.container.Panel
        TypeEditFieldLabel              matlab.ui.control.Label
        TypeEditField                   matlab.ui.control.EditField
        LevelLabel                      matlab.ui.control.Label
        WaveletLevel                    matlab.ui.control.NumericEditField
        ClassificiationTab              matlab.ui.container.Tab
        ClassificationMethodButtonGroup  matlab.ui.container.ButtonGroup
        LDAButton                       matlab.ui.control.RadioButton
        SVMButton                       matlab.ui.control.RadioButton
        kNNButton                       matlab.ui.control.RadioButton
        PerformClassificationButton     matlab.ui.control.Button
        TrainModelButton                matlab.ui.control.Button
        AnalysisTab                     matlab.ui.container.Tab
        ResultsTab                      matlab.ui.container.Tab
    end

    methods (Access = private)

        % Callback function
        function ApplyFraming(app, event)
             
        end

        % Button pushed function: PerformClassificationButton
        function Classification(app, event)
        %% Structure of the choices
        choices =evalin('base','choices');
            
        %% Get the method
        LDA=int8(app.LDAButton.Value);
        SVM=int8(app.SVMButton.Value);
        kNN=int8(app.kNNButton.Value);
        
        if (LDA==1)
            method='LDA';
        elseif (SVM==1)
            method='SVM';
        elseif (kNN==1)
            method='kNN';    
        end
        
        choices.ClassificationMethod=method;
        
        %% Bands and filtering
         NbOfBands=app.NbofbandsEditField.Value;
         ClassicalFrequencyBands=int8(app.ClassicalfrequencybandsButton.Value);
         muRythm=int8(app.muRythmCheckBox.Value);
         alphaRythm=int8(app.alphaRythmCheckBox.Value);
         betaRythm=int8(app.betaRythmCheckBox.Value);
         deltaRythm=int8(app.deltaRythmCheckBox.Value);
         thetaRythm=int8(app.thetaRythmCheckBox.Value);
         
         
         if (ClassicalFrequencyBands==1)         
                if (muRythm==1)
                   FeatureVectorMu =evalin('base','FeatureVectorMu');  

                end
                if (alphaRythm==1)
                   FeatureVectorAlpha =evalin('base','FeatureVectorAlpha');  

                end
                if (betaRythm==1)
                    FeatureVectorBeta =evalin('base','FeatureVectorBeta');  
                end
                if (deltaRythm==1)
                    FeatureVectorDelta =evalin('base','FeatureVectorDelta'); 
                end
                if (thetaRythm==1)
                   FeatureVectorTheta =evalin('base','FeatureVectorTheta');               
                end
            else
                FeatureVectorBand =evalin('base','FeatureVectorBand'); 
                while n<NbOfBands+1
                    n=n+1;
                end
                    % Pour aller chercher chaque Feature Vector par bande de freq, FeatureVectorBand.band{n}
            end              
                %% Assign the choices
                assignin('base', 'choices',choices);
        end

        % Button pushed function: BuildFeatureVectorButton
        function FeatureExtraction(app, event)
           close all;
            
           %% Structure of the choices
            choices =evalin('base','choices');
            
            %% Which Extraction Method is going to be used?
            PowerBands=int8(app.PowerbandsButton.Value);
            Wavelets=int8(app.WaveletsButton.Value);
            AR=int8(app.ARButton.Value);
            PeakDetection=int8(app.PeakDetectionButton.Value);
            Variance=int8(app.VarianceButton.Value);
            TemplateMatching=int8(app.TemplateMatchingButton.Value);
            TimeIntegration=int8(app.TimeIntegrationButton.Value);  
            
            
            %% Windowing and framing
            WindowLength=app.WindowOverlappingEditField.Value;
            choices.windowLength=WindowLength;
            WindowOverlapping=app.WindowLengthEditField.Value;
            choices.WindowOverlapping=WindowOverlapping;
            n=1;
            
            %% Parameters of the different methods
            WaveletType=app.TypeEditField.Value;
            WaveletLevel=app.WaveletLevel.Value;
            AROrder=app.AROrder.Value;
            choices.WaveletType=app.TypeEditField.Value;
            choices.WaveletLevel=app.WaveletLevel.Value;
            choices.AROrder=app.AROrder.Value;
  
            FeatureVectorBand=[];
            %% To choose the method
            if (PeakDetection==1)
                method='PeakDetection';
            elseif (Wavelets==1)
                method='Wavelets';
            elseif (AR==1)
                method='AR';    
            elseif (PowerBands==1)
                method='PowerBands';    
            elseif (Variance==1)
                method='Variance';    
            elseif (TemplateMatching==1)  
                method='TemplateMatching'; 
            elseif (TimeIntegration==1)  
                method='TimeIntegration'; 
            end
            choices.FeatureExtractionMethod=method;
            assignin('base', 'FeatureExtractionMethod',method); % To save the Feature Extraction Method

            
            %% Which type of filtering has been used
            choices.NbOfBands=app.NbofbandsEditField.Value;
            
            %% Load the data
            
            if (choices.ClassicalFrequencyBands==1)         
                if (choices.muRythm==1)
                    myMuSpatialFilteredData=evalin('base','myMuSpatialFilteredData');  
                    [FeatureVectorMu]=myFeatureExtraction(myMuSpatialFilteredData,method,WindowLength,WindowOverlapping,AROrder,WaveletType,WaveletLevel);
                    assignin('base', 'FeatureVectorMu', FeatureVectorMu);
                end
                if (choices.alphaRythm==1)
                    myAlphaSpatialFilteredData=evalin('base','myAlphaSpatialFilteredData');
                    [FeatureVectorAlpha]=myFeatureExtraction(myAlphaSpatialFilteredData,method,WindowLength,WindowOverlapping,AROrder,WaveletType,WaveletLevel);
                    assignin('base', 'FeatureVectorAlpha', FeatureVectorAlpha);
                end
                if (choices.betaRythm==1)
                    myBetaSpatialFilteredData=evalin('base','myBetaSpatialFilteredData');
                    [FeatureVectorBeta]=myFeatureExtraction(myBetaSpatialFilteredData,method,WindowLength,WindowOverlapping,AROrder,WaveletType,WaveletLevel);
                    assignin('base', 'FeatureVectorBeta', FeatureVectorBeta);
                end
                if (choices.deltaRythm==1)
                    myDeltaSpatialFilteredData=evalin('base','myDeltaSpatialFilteredData');
                    [FeatureVectorDelta]=myFeatureExtraction(myDeltaSpatialFilteredData,method,WindowLength,WindowOverlapping,AROrder,WaveletType,WaveletLevel);
                    assignin('base', 'FeatureVectorDelta', FeatureVectorDelta);
                end
                if (choices.thetaRythm==1)
                    myThetaSpatialFilteredData=evalin('base','myThetaSpatialFilteredData');
                    [FeatureVectorTheta]=myFeatureExtraction(myThetaSpatialFilteredData,method,WindowLength,WindowOverlapping,AROrder,WaveletType,WaveletLevel);
                    assignin('base', 'FeatureVectorTheta', FeatureVectorTheta);                
                end
            else
                myFreqBandsSpatialFilteredData=evalin('base','myFreqBandsSpatialFilteredData');
                while n<NbOfBands+1
                    FeatureVectorBand.band{n}=myFeatureExtraction(myFreqBandsSpatialFilteredData.data(n),method,WindowLength,WindowOverlapping,AROrder,WaveletType,WaveletLevel);
                    n=n+1;
                end
                    assignin('base', 'FeatureVectorBand', FeatureVectorBand);  
                    % Pour aller chercher chaque Feature Vector par bande de freq, FeatureVectorBand.band{n}
            end              
            
                %% Assign the choices
                assignin('base', 'choices',choices);
        end

        % Button pushed function: GeneratedataButton
        function Generate(app, event)
            close all;
            
            %% To Generate the signals
            Fs=app.FsEditField.Value;
            SNR=app.SNREditField.Value;
            time_movement=app.MovementStartingTimeEditField.Value;
            t_end=app.DurationEditField.Value;
            time=linspace(0,t_end,Fs*t_end+1);
            assignin('base', 'time',time);
            eye_blink_check=int8(app.EyeblinkCheckBox.Value);
            power_line_check=int8(app.PowerlinesCheckBox.Value);
            myMove=app.MovementDropDown.Value;
            
            if strcmp(myMove,'Right hand')
                movement=1;
            elseif strcmp(myMove,'Left hand')
                movement=2;
            else
                movement=3;
            end
            
            %% Generation of the signals
            [C3, C4, Cz, time]=eeg_generator(Fs,t_end,SNR,movement, time_movement,eye_blink_check,power_line_check);
            
            %% Save the signals and parameters
            rawData=[C3;C4;Cz];
            assignin('base', 'rawData',rawData)
            save('rawData.mat','C3','C4','Cz') 
            parameters=[];
            parameters.fsample=Fs;
            parameters.duration=t_end;
            parameters.time=time;
            assignin('base', 'parameters', parameters);
            save('parameters.mat', 'parameters')
            
            %% Create the FieldTrip Structure for further processing
            [myFtData]=createFtStruct();
            assignin('base', 'myFtData',myFtData);
            
            %% Create the structure of the choices
            choices=[];
            assignin('base', 'choices',choices);
            %% Frequency analysis
            [mySpectralData]=FreqTransform(myFtData, 'raw data');
        end

        % Button pushed function: LoaddataButton
        function Load(app, event)
            filename=app.FilenameEditField.Value
            file=load(filename)
            assignin('base', 'myData', file)
            
        end

        % Button pushed function: ApplyrejectionButton
        function RejectArtifact(app, event)
            close all;
            
            Type=app.FilteringMethodDropDown.Value;
            
            %% Personal frequency bands
            n=1;
            
                if (choices.ClassicalFrequencyBands==1)         
                    if (choices.muRythm==1)
                        myMuTemporalFilteredData=evalin('base','myMuTemporalFilteredData');  
                    end
                    if (choices.alphaRythm==1)
                        myAlphaTemporalFilteredData=evalin('base','myAlphaTemporalFilteredData');           
                    end
                    if (choices.betaRythm==1)
                        myBetaTemporalFilteredData=evalin('base','myBetaTemporalFilteredData');           
            
                    end
                    if (choices.deltaRythm==1)
                        myDeltaTemporalFilteredData=evalin('base','myDeltaTemporalFilteredData');           
                    end
                    if (choices.thetaRythm==1)
                        myThetaTemporalFilteredData=evalin('base','myThetaTemporalFilteredData');
                    end
                else
                        myFreqBandsTemporalFilteredData=evalin('base','myFreqBandsTemporalFilteredData');
                        while n<length(myFreqBandsTemporalFilteredData.data)+1
                        n=n+1;
                        end
                end     
        end

        % Callback function
        function ValueEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ApplyspatialfilteringButton
        function spatial_filtering(app, event)
            close all;
            
            %% Structure of the choices
            choices =evalin('base','choices');
            
            Type=app.FilteringMethodDropDown.Value;
            choices.SpatialFilteringMethod=Type;
            assignin('base', 'SpatialFilteringMethod',Type);
            
            %% Personal frequency bands
            myFreqBandsSpatialFilteredData=[];
            myFreqBandsSpectralData=[];
            n=1;
                if (choices.ClassicalFrequencyBands==1)         
                    if (choices.muRythm==1)
                        myMuTemporalFilteredData=evalin('base','myMuTemporalFilteredData');           
                        myMuSpatialFilteredData=SpatialFiltering(myMuTemporalFilteredData,Type,'mu band [7.5 Hz - 12.5 Hz]');
                       % myMuSpectralData=FreqTransform(myMuSpatialFilteredData,'mu band [7.5 Hz - 12.5 Hz]');
                        assignin('base', 'myMuSpatialFilteredData', myMuSpatialFilteredData);
                    end
                    if (choices.alphaRythm==1)
                        myAlphaTemporalFilteredData=evalin('base','myAlphaTemporalFilteredData');           
                        myAlphaSpatialFilteredData=SpatialFiltering(myAlphaTemporalFilteredData,Type,'alpha band [8 Hz - 15 Hz]');
                        %myAlphaSpectralData=FreqTransform(myAlphaSpatialFilteredData,'alpha band [8 Hz - 15 Hz]');
                        assignin('base', 'myAlphaSpatialFilteredData', myAlphaSpatialFilteredData);
                    end
                    if (choices.betaRythm==1)
                        myBetaTemporalFilteredData=evalin('base','myBetaTemporalFilteredData');           
                        myBetaSpatialFilteredData=SpatialFiltering(myBetaTemporalFilteredData,Type,'beta band  [16 Hz - 31 Hz]');
                        myBetaSpectralData=FreqTransform(myBetaSpatialFilteredData,'beta band  [16 Hz - 31 Hz]');
                        assignin('base', 'myBetaSpatialFilteredData', myBetaSpatialFilteredData);
                    end
                    if (choices.deltaRythm==1)
                        myDeltaTemporalFilteredData=evalin('base','myDeltaTemporalFilteredData');           
                        myDeltaTemporalFilteredData=SpatialFiltering(myDeltaTemporalFilteredData,Type,'delta band [0.1 Hz- 3 Hz]');
                        myDeltaSpectralData=FreqTransform(myDeltaSpatialFilteredData,'delta band [0.1 Hz- 3 Hz]');
                        assignin('base', 'myDeltaSpatialFilteredData', myDeltaSpatialFilteredData);
                    end
                    if (choices.thetaRythm==1)
                        myThetaTemporalFilteredData=evalin('base','myThetaTemporalFilteredData');
                        myThetaSpatialFilteredData=SpatialFiltering(myThetaTemporalFilteredData,Type,'theta band [4 Hz- 7 Hz]');
                        myThetaSpectralData=FreqTransform(myThetaSpatialFilteredData,'theta band [4 Hz- 7 Hz]');
                        assignin('base', 'myThetaSpatialFilteredData', myThetaSpatialFilteredData);
                    end
                else
                        myFreqBandsTemporalFilteredData=evalin('base','myFreqBandsTemporalFilteredData');
                    while n<length(myFreqBandsTemporalFilteredData.data)+1
                        myFreqBandsSpatialFilteredData.data(n)=SpatialFiltering(myFreqBandsTemporalFilteredData.data(n),Type,['band' num2str(n)]);
                        n=n+1;
                    end
                    assignin('base', 'myFreqBandsSpatialFilteredData', myFreqBandsSpatialFilteredData);
                
                end     
            
                    
                %% Assign the choices
                assignin('base', 'choices',choices);

        end

        % Button pushed function: ApplytemporalfilteringButton
        function temporal_filtering(app, event)
            close all;
            %% Get the values from the app
            parameters=evalin('base','parameters');           
            
            %% Structure of the choices
            choices =evalin('base','choices');
            
            %% Not with the different bands
            BPFilter=int8(app.BPFilterCheckBox.Value);
            NotchFilter=int8(app.NotchFilterCheckBox.Value);
            Type=app.TypeDropDown.Value;
            winType=app.WindowTypeDropDown.Value;
            N=app.OrderEditField.Value;
           
            %% Get the FT structure 
            myFtData=evalin('base','myFtData');   
            
            %% With the classical bands
            muRythm=int8(app.muRythmCheckBox.Value);
            choices.muRythm=muRythm;
            
            alphaRythm=int8(app.alphaRythmCheckBox.Value);
            choices.alphaRythm=alphaRythm;
            
            betaRythm=int8(app.betaRythmCheckBox.Value);
            choices.betaRythm=betaRythm;
            
            deltaRythm=int8(app.deltaRythmCheckBox.Value);
            choices.deltaRythm=deltaRythm;
            
            thetaRythm=int8(app.thetaRythmCheckBox.Value);
            choices.thetaRythm=thetaRythm;
            
            %% With the perso bands
            LFreqPerso=app.LFreqEditField_perso.Value;
            HFreqPerso=app.HFreqEditField_perso.Value;
            NbOfBands=app.NbofbandsEditField.Value;
            step=(HFreqPerso-LFreqPerso)/NbOfBands;
            fstart=LFreqPerso;
            fend=LFreqPerso+step;
            n=1;
            
            myFreqBandsTemporalFilteredData=[]; % Create the structure that will contain the structure for each frequency band
            myFreqBandsSpectralData=[];
            ClassicalFrequencyBands=int8(app.ClassicalfrequencybandsButton.Value);
            choices.ClassicalFrequencyBands=ClassicalFrequencyBands;
            
            if (ClassicalFrequencyBands==1)         
                if (muRythm==1)
                    myMuTemporalFilteredData=temporalFtFiltering(myFtData,7.5,12.5,NotchFilter,Type,winType,N,'mu band [7.5 Hz - 12.5 Hz]')
                    myMuSpectralData=FreqTransform(myMuTemporalFilteredData,'mu band [7.5 Hz - 12.5 Hz]');
                    assignin('base', 'myMuTemporalFilteredData', myMuTemporalFilteredData)
                end
                if (alphaRythm==1)
                    myAlphaTemporalFilteredData=temporalFtFiltering(myFtData,8,15,NotchFilter,Type,winType,N,'alpha band [8 Hz - 15 Hz]')
                    myAlphaSpectralData=FreqTransform(myAlphaTemporalFilteredData,'alpha band [8 Hz - 15 Hz]');
                    assignin('base', 'myAlphaTemporalFilteredData', myAlphaTemporalFilteredData)
                end
                if (betaRythm==1)
                    myBetaTemporalFilteredData=temporalFtFiltering(myFtData,16,31,NotchFilter,Type,winType,N,'beta band  [16 Hz - 31 Hz]')
                    myBetaSpectralData=FreqTransform(myBetaTemporalFilteredData,'beta band  [16 Hz - 31 Hz]');
                    assignin('base', 'myBetaTemporalFilteredData', myBetaTemporalFilteredData)
                end
                if (deltaRythm==1)
                    myDeltaTemporalFilteredData=temporalFtFiltering(myFtData,0.1,3,NotchFilter,Type,winType,N,'delta band [0.1 Hz- 3 Hz]')
                    myDeltaSpectralData=FreqTransform(myDeltaTemporalFilteredData,'delta band [0.1 Hz- 3 Hz]');
                    assignin('base', 'myDeltaTemporalFilteredData', myDeltaTemporalFilteredData)
                end
                if (thetaRythm==1)
                    myThetaTemporalFilteredData=temporalFtFiltering(myFtData,4,7,NotchFilter,Type,winType,N,'theta band [4 Hz- 7 Hz]')
                    myThetaSpectralData=FreqTransform(myThetaTemporalFilteredData,'theta band [4 Hz- 7 Hz]');
                    assignin('base', 'myThetaTemporalFilteredData', myThetaTemporalFilteredData)
                end
            else
                while n<NbOfBands+1
                    myFreqBandsTemporalFilteredData.data(n)=temporalFtFiltering(myFtData,fstart,fend,NotchFilter,Type,winType,N,['band ' num2str(n) ' [' num2str(fstart) 'Hz - ' num2str(fend) 'Hz ]' ])
                    myFreqBandsSpectralData.data(n)=FreqTransform(myFreqBandsTemporalFilteredData.data(n),['band ' num2str(n) ' [' num2str(fstart) 'Hz - ' num2str(fend) 'Hz ]' ]);
                    fstart=fstart+step;
                    fend=fend+step;
                    n=n+1;
                end
                    assignin('base', 'myFreqBandsTemporalFilteredData', myFreqBandsTemporalFilteredData);
            end     
                            %% Assign the choices
                assignin('base', 'choices',choices);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.SignaloptionsandartefactsPanel = uipanel(app.DataTab);
            app.SignaloptionsandartefactsPanel.Title = 'Signal options and artefacts';
            app.HzLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.HzLabel.Text = 'Hz';
            app.sLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.sLabel.Text = 's';
            app.dBLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.dBLabel.Text = 'dB';
            app.DurationEditFieldLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.SignaloptionsandartefactsPanel, 'numeric');
            app.DurationEditField.Value = 20;
            app.SNREditFieldLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.SignaloptionsandartefactsPanel, 'numeric');
            app.SNREditField.Value = 10;
            app.EyeblinkCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.EyeblinkCheckBox.Text = 'Eye blink';
            app.EyeblinkCheckBox.Value = true;
            app.EMGeffectsCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.EMGeffectsCheckBox.Text = 'EMG effects';
            app.EMGeffectsCheckBox.Value = true;
            app.EOGeffectsCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.EOGeffectsCheckBox.Text = 'EOG effects';
            app.EOGeffectsCheckBox.Value = true;
            app.FsEditFieldLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.SignaloptionsandartefactsPanel, 'numeric');
            app.FsEditField.Value = 256;
            app.MovementStartingTimeLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.MovementStartingTimeLabel.Text = {'Movement '; 'Starting '; 'Time'};
            app.MovementStartingTimeEditField = uieditfield(app.SignaloptionsandartefactsPanel, 'numeric');
            app.MovementStartingTimeEditField.Value = 5;
            app.GeneratedataButton = uibutton(app.SignaloptionsandartefactsPanel, 'push');
            app.GeneratedataButton.ButtonPushedFcn = createCallbackFcn(app, @Generate, true);
            app.GeneratedataButton.Text = 'Generate data';
            app.PowerlinesCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.PowerlinesCheckBox.Text = 'Power lines';
            app.PowerlinesCheckBox.Value = true;
            app.MovementDropDownLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.MovementDropDownLabel.Text = 'Movement';
            app.MovementDropDown = uidropdown(app.SignaloptionsandartefactsPanel);
            app.MovementDropDown.Items = {'Right hand', 'Left hand', 'Feet'};
            app.MovementDropDown.Value = 'Right hand';
            app.FilePanel = uipanel(app.DataTab);
            app.FilePanel.Title = 'File';
            app.FilenameEditFieldLabel = uilabel(app.FilePanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.FilePanel, 'text');
            app.LoaddataButton = uibutton(app.FilePanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @Load, true);
            app.LoaddataButton.Text = 'Load data';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Pre-processing';
            app.SpatialFilteringPanel = uipanel(app.PreprocessingTab);
            app.SpatialFilteringPanel.Title = 'Spatial Filtering';
            app.ApplyspatialfilteringButton = uibutton(app.SpatialFilteringPanel, 'push');
            app.ApplyspatialfilteringButton.ButtonPushedFcn = createCallbackFcn(app, @spatial_filtering, true);
            app.ApplyspatialfilteringButton.Text = 'Apply spatial filtering';
            app.FilteringMethodDropDownLabel = uilabel(app.SpatialFilteringPanel);
            app.FilteringMethodDropDownLabel.Text = 'Filtering Method';
            app.FilteringMethodDropDown = uidropdown(app.SpatialFilteringPanel);
            app.FilteringMethodDropDown.Items = {'CAR', 'ICA', 'CSP', 'LAP'};
            app.FilteringMethodDropDown.Value = 'CAR';
            app.TemporalFilteringPanel = uipanel(app.PreprocessingTab);
            app.TemporalFilteringPanel.Title = 'Temporal Filtering';
            app.BandPassFilterParametersPanel = uipanel(app.TemporalFilteringPanel);
            app.BandPassFilterParametersPanel.Title = 'Band Pass Filter Parameters';
            app.TypeDropDownLabel = uilabel(app.BandPassFilterParametersPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.BandPassFilterParametersPanel);
            app.TypeDropDown.Items = {'fir', 'but', 'firws'};
            app.TypeDropDown.Value = 'but';
            app.OrderEditFieldLabel = uilabel(app.BandPassFilterParametersPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.BandPassFilterParametersPanel, 'numeric');
            app.OrderEditField.Value = 4;
            app.WindowTypeLabel = uilabel(app.BandPassFilterParametersPanel);
            app.WindowTypeLabel.Text = {'Window '; 'Type'};
            app.WindowTypeDropDown = uidropdown(app.BandPassFilterParametersPanel);
            app.WindowTypeDropDown.Items = {'', 'hann', 'hamming', 'blackman', 'kaiser'};
            app.WindowTypeDropDown.Value = 'hamming';
            app.NotchFilterPanel = uipanel(app.TemporalFilteringPanel);
            app.NotchFilterPanel.Title = 'Notch Filter ';
            app.FreqEditFieldLabel = uilabel(app.NotchFilterPanel);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.NotchFilterPanel, 'numeric');
            app.FreqEditField.Value = 50;
            app.HzLabel_6 = uilabel(app.NotchFilterPanel);
            app.HzLabel_6.Text = 'Hz';
            app.ApplytemporalfilteringButton = uibutton(app.TemporalFilteringPanel, 'push');
            app.ApplytemporalfilteringButton.ButtonPushedFcn = createCallbackFcn(app, @temporal_filtering, true);
            app.ApplytemporalfilteringButton.Text = 'Apply temporal filtering';
            app.FiltersPanel = uipanel(app.TemporalFilteringPanel);
            app.FiltersPanel.Title = 'Filters';
            app.NotchFilterCheckBox = uicheckbox(app.FiltersPanel);
            app.NotchFilterCheckBox.Text = 'Notch Filter';
            app.NotchFilterCheckBox.Value = true;
            app.BPFilterCheckBox = uicheckbox(app.FiltersPanel);
            app.BPFilterCheckBox.Text = 'BP Filter';
            app.BPFilterCheckBox.Value = true;
            app.FrequencybandsButtonGroup = uibuttongroup(app.TemporalFilteringPanel);
            app.FrequencybandsButtonGroup.Title = 'Frequency bands';
            app.ClassicalfrequencybandsButton = uiradiobutton(app.FrequencybandsButtonGroup);
            app.ClassicalfrequencybandsButton.Text = 'Classical frequency bands';
            app.ClassicalfrequencybandsButton.Value = true;
            app.PersonnalisedfrequencybandsButton = uiradiobutton(app.FrequencybandsButtonGroup);
            app.PersonnalisedfrequencybandsButton.Text = 'Personnalised frequency bands';
            app.ClassicalfrequencybandsPanel = uipanel(app.TemporalFilteringPanel);
            app.ClassicalfrequencybandsPanel.Title = 'Classical frequency bands';
            app.muRythmCheckBox = uicheckbox(app.ClassicalfrequencybandsPanel);
            app.muRythmCheckBox.Text = 'μ Rythm [7.5 Hz - 12.5 Hz]';
            app.muRythmCheckBox.Value = true;
            app.deltaRythmCheckBox = uicheckbox(app.ClassicalfrequencybandsPanel);
            app.deltaRythmCheckBox.Text = 'δ Rythm [0.1 Hz - 3 Hz]';
            app.thetaRythmCheckBox = uicheckbox(app.ClassicalfrequencybandsPanel);
            app.thetaRythmCheckBox.Text = 'θ Rythm [4 Hz - 7 Hz]';
            app.alphaRythmCheckBox = uicheckbox(app.ClassicalfrequencybandsPanel);
            app.alphaRythmCheckBox.Text = 'α Rythm [8 Hz - 15 Hz]';
            app.alphaRythmCheckBox.Value = true;
            app.betaRythmCheckBox = uicheckbox(app.ClassicalfrequencybandsPanel);
            app.betaRythmCheckBox.Text = ' β Rythm [16 Hz - 31 Hz]';
            app.PersonnalisedfrequencybandsPanel = uipanel(app.TemporalFilteringPanel);
            app.PersonnalisedfrequencybandsPanel.Title = 'Personnalised frequency bands';
            app.NbofbandsEditFieldLabel = uilabel(app.PersonnalisedfrequencybandsPanel);
            app.NbofbandsEditFieldLabel.Text = 'Nb of bands';
            app.NbofbandsEditField = uieditfield(app.PersonnalisedfrequencybandsPanel, 'numeric');
            app.NbofbandsEditField.Value = 1;
            app.LFreqEditField_2Label = uilabel(app.PersonnalisedfrequencybandsPanel);
            app.LFreqEditField_2Label.Text = 'LFreq';
            app.LFreqEditField_perso = uieditfield(app.PersonnalisedfrequencybandsPanel, 'numeric');
            app.LFreqEditField_perso.Value = 7;
            app.HFreqEditField_2Label = uilabel(app.PersonnalisedfrequencybandsPanel);
            app.HFreqEditField_2Label.Text = 'HFreq';
            app.HFreqEditField_perso = uieditfield(app.PersonnalisedfrequencybandsPanel, 'numeric');
            app.HFreqEditField_perso.Value = 14;
            app.ArtifactRejectionPanel = uipanel(app.PreprocessingTab);
            app.ArtifactRejectionPanel.Title = 'Artifact Rejection';
            app.ApplyrejectionButton = uibutton(app.ArtifactRejectionPanel, 'push');
            app.ApplyrejectionButton.ButtonPushedFcn = createCallbackFcn(app, @RejectArtifact, true);
            app.ApplyrejectionButton.Text = 'Apply rejection';
            app.ExtractionTab = uitab(app.TabGroup);
            app.ExtractionTab.Title = 'Extraction';
            app.ExtractionMethodButtonGroup = uibuttongroup(app.ExtractionTab);
            app.ExtractionMethodButtonGroup.Title = 'Extraction Method';
            app.PowerbandsButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.PowerbandsButton.Text = 'Power bands';
            app.PowerbandsButton.Value = true;
            app.WaveletsButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.WaveletsButton.Text = 'Wavelets';
            app.ARButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.ARButton.Text = 'AR';
            app.PeakDetectionButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.PeakDetectionButton.Text = 'Peak Detection';
            app.VarianceButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.VarianceButton.Text = 'Variance';
            app.TemplateMatchingButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.TemplateMatchingButton.Text = 'TemplateMatching';
            app.TimeIntegrationButton = uiradiobutton(app.ExtractionMethodButtonGroup);
            app.TimeIntegrationButton.Text = 'Time Integration';
            app.BuildFeatureVectorButton = uibutton(app.ExtractionMethodButtonGroup, 'push');
            app.BuildFeatureVectorButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureExtraction, true);
            app.BuildFeatureVectorButton.Text = 'Build Feature Vector';
            app.WindowingandframingPanel = uipanel(app.ExtractionTab);
            app.WindowingandframingPanel.Title = 'Windowing and framing';
            app.WindowLengthEditFieldLabel = uilabel(app.WindowingandframingPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.WindowingandframingPanel, 'numeric');
            app.WindowLengthEditField.Value = 1000;
            app.msLabel = uilabel(app.WindowingandframingPanel);
            app.msLabel.Text = 'ms';
            app.WindowOverlappingEditField_2Label = uilabel(app.ExtractionTab);
            app.WindowOverlappingEditField_2Label.Text = 'Window Overlapping';
            app.WindowOverlappingEditField = uieditfield(app.ExtractionTab, 'numeric');
            app.WindowOverlappingEditField.Value = 1;
            app.ARPanel = uipanel(app.ExtractionTab);
            app.ARPanel.Title = 'AR';
            app.OrderEditField_2Label = uilabel(app.ARPanel);
            app.OrderEditField_2Label.Text = 'Order';
            app.AROrder = uieditfield(app.ARPanel, 'numeric');
            app.AROrder.Value = 10;
            app.WaveletsPanel = uipanel(app.ExtractionTab);
            app.WaveletsPanel.Title = 'Wavelets';
            app.TypeEditFieldLabel = uilabel(app.WaveletsPanel);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.WaveletsPanel, 'text');
            app.TypeEditField.Value = 'db1';
            app.LevelLabel = uilabel(app.WaveletsPanel);
            app.LevelLabel.Text = 'Level';
            app.WaveletLevel = uieditfield(app.WaveletsPanel, 'numeric');
            app.WaveletLevel.Value = 5;
            app.ClassificiationTab = uitab(app.TabGroup);
            app.ClassificiationTab.Title = 'Classificiation';
            app.ClassificationMethodButtonGroup = uibuttongroup(app.ClassificiationTab);
            app.ClassificationMethodButtonGroup.Title = 'Classification Method';
            app.LDAButton = uiradiobutton(app.ClassificationMethodButtonGroup);
            app.LDAButton.Text = 'LDA';
            app.SVMButton = uiradiobutton(app.ClassificationMethodButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.kNNButton = uiradiobutton(app.ClassificationMethodButtonGroup);
            app.kNNButton.Text = 'kNN';
            app.kNNButton.Value = true;
            app.PerformClassificationButton = uibutton(app.ClassificiationTab, 'push');
            app.PerformClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @Classification, true);
            app.PerformClassificationButton.Text = 'Perform Classification';
            app.TrainModelButton = uibutton(app.ClassificiationTab, 'push');
            app.TrainModelButton.Text = 'Train Model';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BMI_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end